classdef Main_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TimetakenEditField            matlab.ui.control.EditField
        TimetakenEditFieldLabel       matlab.ui.control.Label
        WMRSButton                    matlab.ui.control.Button
        AcquireButton                 matlab.ui.control.Button
        MaxRamanshiftEditFieldLabel   matlab.ui.control.Label
        MaxRamanshiftEditField        matlab.ui.control.NumericEditField
        MinRamanShiftEditFieldLabel   matlab.ui.control.Label
        MinRamanShiftEditField        matlab.ui.control.NumericEditField
        MaxWavelengthEditFieldLabel   matlab.ui.control.Label
        MaxWavelengthEditField        matlab.ui.control.NumericEditField
        CCDTempEditFieldLabel         matlab.ui.control.Label
        CCDTempEditField              matlab.ui.control.NumericEditField
        MinWavelengthEditFieldLabel   matlab.ui.control.Label
        MinWavelengthEditField        matlab.ui.control.NumericEditField
        SlitWidthEditFieldLabel       matlab.ui.control.Label
        SlitWidthEditField            matlab.ui.control.NumericEditField
        TuningStepsEditFieldLabel     matlab.ui.control.Label
        TuningStepsEditField          matlab.ui.control.NumericEditField
        CentralWavelengthEditFieldLabel  matlab.ui.control.Label
        CentralWavelengthEditField    matlab.ui.control.NumericEditField
        LaserPowerEditFieldLabel      matlab.ui.control.Label
        LaserPowerEditField           matlab.ui.control.NumericEditField
        SpectraAquiredEditFieldLabel  matlab.ui.control.Label
        SpectraAquiredEditField       matlab.ui.control.NumericEditField
        PatientIDEditFieldLabel       matlab.ui.control.Label
        PatientIDEditField            matlab.ui.control.EditField
        SingleRamanSpectraButton      matlab.ui.control.Button
        EngineeringModeButton         matlab.ui.control.Button
        AbortButton                   matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        SetSavePathButton             matlab.ui.control.Button
        LaserShutterButton            matlab.ui.control.Button
        ClinicalModeButton            matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SpectraAcquired = 0 % Number of spectra acquired
        PatientId % Patient Id
        SaveDir % Path to directory to save spectra
        tmr
    end
    properties (Access = public)
        time = 0
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % https://www.mathworks.com/matlabcentral/fileexchange/24861-41-complete-gui-examples
            app.tmr = timer('TimerFcn',{@updater, app},...
                        'Period',1,...  % Update the time every 60 seconds.
                        'StartDelay',0,... % In seconds.
                        'TasksToExecute',inf,...  % number of times to update
                        'ExecutionMode','fixedSpacing');
        end

        % Button pushed function: AcquireButton
        function AcquireButtonPushed(app, event)
            if app.time == 0
                start(app.tmr);
            end
            T = readtable("raman.csv");
            plot(app.UIAxes, T.RamanShift_1_cm_, T.Counts, '-r');
            hold(app.UIAxes);
            
            app.SpectraAcquired = app.SpectraAcquired + 1;
            app.SpectraAquiredEditField.Value = app.SpectraAcquired;
        end

        % Callback function: ExitButton, UIFigure
        function ExitButtonPushed(app, event)
            answer = questdlg("Do you want to shutdown the software?");
            disp(answer)
            if answer == "Yes"
                stop(app.tmr);
                delete(app.tmr);
                delete(app);
            end
        end

        % Button pushed function: SetSavePathButton
        function SetSavePathButtonPushed(app, event)
            app.SaveDir = uigetdir("", "Patient data Folder");
        end

        % Value changed function: PatientIDEditField
        function PatientIDEditFieldValueChanged(app, event)
            value = app.PatientIDEditField.Value;
            app.PatientId = value;
        end

        % Button pushed function: EngineeringModeButton
        function EngineeringModeButtonPushed(app, event)
            answer = inputdlg("Enter Password");
            if answer == "proscope2023"
                app.LaserShutterButton.Visible = 'on';
                
                app.CentralWavelengthEditField.Visible = 'on';
                app.CentralWavelengthEditFieldLabel.Visible = 'on';
    
                app.CCDTempEditField.Visible = 'on';
                app.CCDTempEditFieldLabel.Visible = 'on';
                
                app.MinRamanShiftEditField.Visible = 'on';
                app.MinRamanShiftEditFieldLabel.Visible = 'on';
    
                app.MaxRamanshiftEditField.Visible = 'on';
                app.MaxRamanshiftEditFieldLabel.Visible = 'on';
    
                app.MinWavelengthEditField.Visible = 'on';
                app.MinWavelengthEditFieldLabel.Visible = 'on';
    
                app.MaxWavelengthEditField.Visible = 'on';
                app.MaxWavelengthEditFieldLabel.Visible = 'on';
    
                app.LaserPowerEditField.Visible = "on";
                app.LaserPowerEditFieldLabel.Visible = "on";
                
                app.SlitWidthEditField.Visible = "on";
                app.SlitWidthEditFieldLabel.Visible = "on";
                
                app.TuningStepsEditField.Visible = "on";
                app.TuningStepsEditFieldLabel.Visible = "on";
                
                app.EngineeringModeButton.Visible = "off";
                app.ClinicalModeButton.Visible = "on";
            else
                uiwait(msgbox("Wrong Password!","Security Error","error", "modal"));
            end 
        end

        % Button pushed function: ClinicalModeButton
        function ClinicalModeButtonPushed(app, event)
          app.LaserShutterButton.Visible = 'off';
            
            app.CentralWavelengthEditField.Visible = 'off';
            app.CentralWavelengthEditFieldLabel.Visible = 'off';

            app.CCDTempEditField.Visible = 'off';
            app.CCDTempEditFieldLabel.Visible = 'off';
            
            app.MinRamanShiftEditField.Visible = 'off';
            app.MinRamanShiftEditFieldLabel.Visible = 'off';

            app.MaxRamanshiftEditField.Visible = 'off';
            app.MaxRamanshiftEditFieldLabel.Visible = 'off';

            app.MinWavelengthEditField.Visible = 'off';
            app.MinWavelengthEditFieldLabel.Visible = 'off';

            app.MaxWavelengthEditField.Visible = 'off';
            app.MaxWavelengthEditFieldLabel.Visible = 'off';

            app.LaserPowerEditField.Visible = "off";
            app.LaserPowerEditFieldLabel.Visible = "off";
            
            app.SlitWidthEditField.Visible = "off";
            app.SlitWidthEditFieldLabel.Visible = "off";
            
            app.TuningStepsEditField.Visible = "off";
            app.TuningStepsEditFieldLabel.Visible = "off";
            
            app.EngineeringModeButton.Visible = "on";
            app.ClinicalModeButton.Visible = "off";
        end

        % Button pushed function: WMRSButton
        function WMRSButtonPushed(app, event)
            app.WMRSButton.Visible = "off";
            app.SingleRamanSpectraButton.Visible = "on";
            title(app.UIAxes, 'WMR Spectra');
            % todo change laser and spectrometer properties...
        end

        % Button pushed function: SingleRamanSpectraButton
        function SingleRamanSpectraButtonPushed(app, event)
            app.WMRSButton.Visible = "on";
            app.SingleRamanSpectraButton.Visible = "off";
            title(app.UIAxes, 'Raman Spectra');
            % todo change laser and spectrometer properties...

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raman Spectra')
            xlabel(app.UIAxes, 'Wavenumber/cm^{-1}')
            ylabel(app.UIAxes, 'Raman Intensity/arb.')
            zlabel(app.UIAxes, 'Z')
            app.ClinicalModeButton = uibutton(app.UIFigure, 'push');
            app.ClinicalModeButton.ButtonPushedFcn = createCallbackFcn(app, @ClinicalModeButtonPushed, true);
            app.ClinicalModeButton.Visible = 'off';
            app.ClinicalModeButton.Text = 'Clinical Mode >>>';
            app.LaserShutterButton = uibutton(app.UIFigure, 'push');
            app.LaserShutterButton.Visible = 'off';
            app.LaserShutterButton.Text = 'Laser Shutter';
            app.SetSavePathButton = uibutton(app.UIFigure, 'push');
            app.SetSavePathButton.ButtonPushedFcn = createCallbackFcn(app, @SetSavePathButtonPushed, true);
            app.SetSavePathButton.Text = 'Set Save Path';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.AbortButton = uibutton(app.UIFigure, 'push');
            app.AbortButton.Text = 'Abort';
            app.EngineeringModeButton = uibutton(app.UIFigure, 'push');
            app.EngineeringModeButton.ButtonPushedFcn = createCallbackFcn(app, @EngineeringModeButtonPushed, true);
            app.EngineeringModeButton.Text = 'Engineering Mode >>>';
            app.SingleRamanSpectraButton = uibutton(app.UIFigure, 'push');
            app.SingleRamanSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @SingleRamanSpectraButtonPushed, true);
            app.SingleRamanSpectraButton.Visible = 'off';
            app.SingleRamanSpectraButton.Text = 'Single Raman Spectra';
            app.PatientIDEditField = uieditfield(app.UIFigure, 'text');
            app.PatientIDEditField.ValueChangedFcn = createCallbackFcn(app, @PatientIDEditFieldValueChanged, true);
            app.PatientIDEditFieldLabel = uilabel(app.UIFigure);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.SpectraAquiredEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpectraAquiredEditField.Limits = [0 Inf];
            app.SpectraAquiredEditField.ValueDisplayFormat = '%.0f';
            app.SpectraAquiredEditFieldLabel = uilabel(app.UIFigure);
            app.SpectraAquiredEditFieldLabel.Text = 'Spectra Aquired:';
            app.LaserPowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.LaserPowerEditField.Limits = [0 Inf];
            app.LaserPowerEditField.Visible = 'off';
            app.LaserPowerEditFieldLabel = uilabel(app.UIFigure);
            app.LaserPowerEditFieldLabel.Visible = 'off';
            app.LaserPowerEditFieldLabel.Text = 'Laser Power';
            app.CentralWavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.CentralWavelengthEditField.Limits = [0 Inf];
            app.CentralWavelengthEditField.Visible = 'off';
            app.CentralWavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.CentralWavelengthEditFieldLabel.Visible = 'off';
            app.CentralWavelengthEditFieldLabel.Text = 'Central Wavelength';
            app.TuningStepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TuningStepsEditField.Limits = [0 Inf];
            app.TuningStepsEditField.Visible = 'off';
            app.TuningStepsEditFieldLabel = uilabel(app.UIFigure);
            app.TuningStepsEditFieldLabel.Visible = 'off';
            app.TuningStepsEditFieldLabel.Text = 'Tuning Steps';
            app.SlitWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SlitWidthEditField.Limits = [0 Inf];
            app.SlitWidthEditField.Visible = 'off';
            app.SlitWidthEditFieldLabel = uilabel(app.UIFigure);
            app.SlitWidthEditFieldLabel.Visible = 'off';
            app.SlitWidthEditFieldLabel.Text = 'Slit Width';
            app.MinWavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinWavelengthEditField.Limits = [0 Inf];
            app.MinWavelengthEditField.Visible = 'off';
            app.MinWavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.MinWavelengthEditFieldLabel.Visible = 'off';
            app.MinWavelengthEditFieldLabel.Text = 'Min Wavelength';
            app.CCDTempEditField = uieditfield(app.UIFigure, 'numeric');
            app.CCDTempEditField.Visible = 'off';
            app.CCDTempEditFieldLabel = uilabel(app.UIFigure);
            app.CCDTempEditFieldLabel.Visible = 'off';
            app.CCDTempEditFieldLabel.Text = 'CCD Temp';
            app.MaxWavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxWavelengthEditField.Limits = [0 Inf];
            app.MaxWavelengthEditField.Visible = 'off';
            app.MaxWavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.MaxWavelengthEditFieldLabel.Visible = 'off';
            app.MaxWavelengthEditFieldLabel.Text = 'Max Wavelength';
            app.MinRamanShiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinRamanShiftEditField.Visible = 'off';
            app.MinRamanShiftEditFieldLabel = uilabel(app.UIFigure);
            app.MinRamanShiftEditFieldLabel.Visible = 'off';
            app.MinRamanShiftEditFieldLabel.Text = 'Min Raman Shift';
            app.MaxRamanshiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxRamanshiftEditField.Visible = 'off';
            app.MaxRamanshiftEditFieldLabel = uilabel(app.UIFigure);
            app.MaxRamanshiftEditFieldLabel.Visible = 'off';
            app.MaxRamanshiftEditFieldLabel.Text = 'Max Raman shift';
            app.AcquireButton = uibutton(app.UIFigure, 'push');
            app.AcquireButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireButtonPushed, true);
            app.AcquireButton.Text = 'Acquire';
            app.WMRSButton = uibutton(app.UIFigure, 'push');
            app.WMRSButton.ButtonPushedFcn = createCallbackFcn(app, @WMRSButtonPushed, true);
            app.WMRSButton.Text = 'WMRS';
            app.TimetakenEditFieldLabel = uilabel(app.UIFigure);
            app.TimetakenEditFieldLabel.Text = 'Time taken';
            app.TimetakenEditField = uieditfield(app.UIFigure, 'text');
            app.TimetakenEditField.Placeholder = '00:00:00';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end