classdef calibration_popup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SetSavePathButton  matlab.ui.control.Button
        SaveButton         matlab.ui.control.Button
        CalibrateButton    matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SaveDir % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            free_bytes = java.io.File(".").getFreeSpace();
            free_Gbytes = free_bytes / (1024^3);
            if free_Gbytes < 10
                if free_Gbytes < 5
                    uiwait(msgbox("Not Enough Memory on Hard Drive!","Memory Error","error", "modal"));
                    delete(app);
                end
                uiwait(msgbox("Warning, less than 10Gb of disk space free!","Memory Warning","warn", "modal"));
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % save code
            %blah
            
            delete(app);
            run("Main_GUI.mlapp");
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            app.SaveButton.Enable = "on";
        end

        % Callback function
        function SavePathEditFieldValueChanged(app, event)

        end

        % Button pushed function: SetSavePathButton
        function SetSavePathButtonPushed(app, event)
            app.SaveDir = uigetdir("", "Patient data Folder");
            app.CalibrateButton.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Calibration Spectra')
            xlabel(app.UIAxes, 'Wavenumber/cm^-1')
            ylabel(app.UIAxes, 'Intensity/arb.')
            zlabel(app.UIAxes, 'Z')
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Enable = 'off';
            app.CalibrateButton.Text = 'Calibrate';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.SetSavePathButton = uibutton(app.UIFigure, 'push');
            app.SetSavePathButton.ButtonPushedFcn = createCallbackFcn(app, @SetSavePathButtonPushed, true);
            app.SetSavePathButton.Text = 'Set Save Path';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calibration_popup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end